classdef FirstWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRoot          matlab.ui.Figure
        GLRoot          matlab.ui.container.GridLayout
        GLBody          matlab.ui.container.GridLayout
        GLGraphics      matlab.ui.container.GridLayout
        GLAnswer        matlab.ui.container.GridLayout
        LAnswer3        matlab.ui.control.Label
        LAnswer2        matlab.ui.control.Label
        LAnswer1        matlab.ui.control.Label
        LT3             matlab.ui.control.Label
        LT2             matlab.ui.control.Label
        LT1             matlab.ui.control.Label
        BodyL2          matlab.ui.control.Label
        BodyL1          matlab.ui.control.Label
        UIAGraphic      matlab.ui.control.UIAxes
        GLInputOutput   matlab.ui.container.GridLayout
        GLMatchFunc     matlab.ui.container.GridLayout
        EF4             matlab.ui.control.NumericEditField
        L4              matlab.ui.control.Label
        L3              matlab.ui.control.Label
        I3              matlab.ui.control.Image
        L2              matlab.ui.control.Label
        EF3             matlab.ui.control.NumericEditField
        I2              matlab.ui.control.Image
        L1              matlab.ui.control.Label
        EF2             matlab.ui.control.NumericEditField
        I1              matlab.ui.control.Image
        EF1             matlab.ui.control.NumericEditField
        GLMathSign      matlab.ui.container.GridLayout
        BCalculate      matlab.ui.control.Button
        GLMenue         matlab.ui.container.GridLayout
        InfoButton      matlab.ui.control.Button
        RollBackButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollBackButton
        function RollBackButtonPushed(app, event)
            % Close window (FirstWindow)
            delete(app.UIRoot)

            % Open Root window
            RootWindow
        end

        % Button pushed function: BCalculate
        function BCalculateButtonPushed(app, event)
        % Settings Graphic
        app.UIAGraphic.XLim = [-50, 50];
        app.UIAGraphic.YLim = [-50, 50];

        % Собираем значеия, введённые пользователем.
        t3 = get(app.EF1, 'Value');
        t2 = get(app.EF2, 'Value');
        t1 = get(app.EF3, 'Value');
        t0 = get(app.EF4, 'Value');

        % Расчитываем значения функции в некоторых узловых точках.
        x_arr = -100 : 0.01 : 100;
        fx_arr = t3 .* (x_arr).^3 + t2 .* (x_arr).^2 + t1 .* (x_arr) + t0;
        %disp(fx_arr)

        equation_root = [nan, nan, nan];

        % Проверяем начальные условия.
        if t3 && ~ t2 && ~ t1
            root = nthroot(-t0, 3);
            set(app.LAnswer1, 'Text', num2str(round(root, 3)))
            set(app.LAnswer2, 'Text', num2str(round(root, 3)))
            set(app.LAnswer3, 'Text', num2str(round(root, 3)))
            plot(app.UIAGraphic, x_arr, fx_arr)
            return
        
        elseif ~ t3 && ~ t2 && ~ t1 && ~t0
            set(app.LAnswer1, 'Text', 'any')
            set(app.LAnswer2, 'Text', 'any')
            set(app.LAnswer3, 'Text', 'any')
            plot(app.UIAGraphic, x_arr, fx_arr)
            return
            
        end

        % Производим поиск корней уравнения которые могут быть
        % кратны узловым точкам ( а вдруг повезёт ).
    
        ind_arr = 1;
        for index = 1 : 1 : length(fx_arr)
            if fx_arr(index) == 0.0
                if get_derivative_bool(x_arr(index - 1), x_arr(index + 1), 0.001, t3, t2, t1, t0)
                    equation_root(ind_arr) = x_arr(index);
                    equation_root(ind_arr + 1) = x_arr(index);
                    ind_arr = ind_arr + 2;

                else
                    equation_root(ind_arr) = x_arr(index);
                    ind_arr = ind_arr + 1;
                end

                %disp(x_arr(index))
            end
        end
        %disp(equation_root)

        % Находим интервалы на которых функция обращается
        % в нуль. Заведомо будем уверены, что точных корней
        % мы не определим -- то есть будут только интервалы
        % на которых находятся корни.
        valid_x_interval = [ ];
        
        for index = 1 : 1 : length(x_arr) - 1
            if ( ( fx_arr(index) > 0 && fx_arr(index + 1) < 0 ) || ...
                 ( fx_arr(index) < 0 && fx_arr(index + 1) > 0 ) )
            
                valid_x_interval(end + 1) = x_arr(index);
                valid_x_interval(end + 1) = x_arr(index + 1);
            end
        end

        %disp(valid_x_interval)

        % Расчитываем корни уравнения.
        for index = 1 : 2 : length(valid_x_interval)
            x_root = get_equation_root(valid_x_interval(index), valid_x_interval(index + 1), ...
                                       0.001, t3, t2, t1, t0);

            if get_derivative_bool(valid_x_interval(index), valid_x_interval(index + 1), ...
                                   0.001, t3, t2, t1, t0)

                equation_root(ind_arr) = x_root;
                equation_root(ind_arr + 1) = x_root;
                ind_arr = ind_arr + 2;

            else
                equation_root(ind_arr) = x_root;
                ind_arr = ind_arr + 1;
            end

        end

        % Выводим сообщение с ответом, расчитанным по встроенным
        % функциям MATLAB.
        valid_integval = get_valid_intervals(-100, 100, 0.001, t3, t2, t1, t0);
        %disp(valid_x_interval)
        myfunc = @(x) t3 .* (x).^3 + t2 .* (x).^2 + t1 .* (x) + t0;

        roots = [nan, nan, nan];
        
        ind = 1;
        for index = 1 : 2 : length(valid_integval)
           roots(ind) = fzero(myfunc, [valid_integval(index), valid_integval(index + 1)]);
           ind = ind + 1;
        end

        str = {strcat('t1 =', num2str(roots(1)));...
               strcat('t2 =', num2str(roots(2)));...
               strcat('t3 =', num2str(roots(3)))};

        message = msgbox(str, 'Correct answer');
        set(message, 'Position', [500 400 200 70]);
        
        message;

        %disp(roots);

        % Выводим расчитанные корни на экран.
        set(app.LAnswer1, 'Text', num2str(round(equation_root(1), 3)))
        set(app.LAnswer2, 'Text', num2str(round(equation_root(2), 3)))
        set(app.LAnswer3, 'Text', num2str(round(equation_root(3), 3)))

        % Рисуем график.
        plot(app.UIAGraphic, x_arr, fx_arr)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRoot = uifigure('Visible', 'off');
            app.UIRoot.Name = 'Решение нелинейного уравнения';
            app.GLRoot = uigridlayout(app.UIRoot);
            app.GLMenue = uigridlayout(app.GLRoot);
            app.RollBackButton = uibutton(app.GLMenue, 'push');
            app.RollBackButton.ButtonPushedFcn = createCallbackFcn(app, @RollBackButtonPushed, true);
            app.RollBackButton.Text = 'RollBack';
            app.InfoButton = uibutton(app.GLMenue, 'push');
            app.InfoButton.Text = 'Info';
            app.GLBody = uigridlayout(app.GLRoot);
            app.GLInputOutput = uigridlayout(app.GLBody);
            app.GLMathSign = uigridlayout(app.GLInputOutput);
            app.BCalculate = uibutton(app.GLMathSign, 'push');
            app.BCalculate.ButtonPushedFcn = createCallbackFcn(app, @BCalculateButtonPushed, true);
            app.BCalculate.Text = 'Решить';
            app.GLMatchFunc = uigridlayout(app.GLInputOutput);
            app.EF1 = uieditfield(app.GLMatchFunc, 'numeric');
            app.EF1.Limits = [-999 999];
            app.EF1.Value = 3;
            app.I1 = uiimage(app.GLMatchFunc);
            app.I1.ImageSource = 'math_func_1_1.png';
            app.EF2 = uieditfield(app.GLMatchFunc, 'numeric');
            app.EF2.Limits = [-999 999];
            app.EF2.Value = 7;
            app.L1 = uilabel(app.GLMatchFunc);
            app.L1.Text = '+';
            app.I2 = uiimage(app.GLMatchFunc);
            app.I2.ImageSource = 'math_func_1_2.png';
            app.EF3 = uieditfield(app.GLMatchFunc, 'numeric');
            app.EF3.Limits = [-999 999];
            app.EF3.Value = 2;
            app.L2 = uilabel(app.GLMatchFunc);
            app.L2.Text = '+';
            app.I3 = uiimage(app.GLMatchFunc);
            app.I3.ImageSource = 'math_func_1_3.png';
            app.L3 = uilabel(app.GLMatchFunc);
            app.L3.Text = '+';
            app.L4 = uilabel(app.GLMatchFunc);
            app.L4.Text = '  =  0 ';
            app.EF4 = uieditfield(app.GLMatchFunc, 'numeric');
            app.EF4.Limits = [-999 999];
            app.EF4.Value = 0.1;
            app.GLGraphics = uigridlayout(app.GLBody);
            app.UIAGraphic = uiaxes(app.GLGraphics);
            xlabel(app.UIAGraphic, 'X')
            ylabel(app.UIAGraphic, 'Y')
            zlabel(app.UIAGraphic, 'Z')
            app.BodyL1 = uilabel(app.GLGraphics);
            app.BodyL1.Text = 'Корни:';
            app.BodyL2 = uilabel(app.GLGraphics);
            app.BodyL2.Text = 'График функции:';
            app.GLAnswer = uigridlayout(app.GLGraphics);
            app.LT1 = uilabel(app.GLAnswer);
            app.LT1.Text = 't1 =';
            app.LT2 = uilabel(app.GLAnswer);
            app.LT2.Text = 't2 =';
            app.LT3 = uilabel(app.GLAnswer);
            app.LT3.Text = 't3 =';
            app.LAnswer1 = uilabel(app.GLAnswer);
            app.LAnswer1.Text = 'ans';
            app.LAnswer2 = uilabel(app.GLAnswer);
            app.LAnswer2.Text = 'ans';
            app.LAnswer3 = uilabel(app.GLAnswer);
            app.LAnswer3.Text = 'ans';
            % Show the figure after all components are created
            app.UIRoot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FirstWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRoot)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRoot)
        end
    end
end