classdef SecondWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRoot          matlab.ui.Figure
        GLRoot          matlab.ui.container.GridLayout
        GLBody          matlab.ui.container.GridLayout
        GLGraphic       matlab.ui.container.GridLayout
        LGraphic        matlab.ui.control.Label
        UIAGraphic      matlab.ui.control.UIAxes
        GLInputOutput   matlab.ui.container.GridLayout
        GLStartValue    matlab.ui.container.GridLayout
        EFStartFx2      matlab.ui.control.NumericEditField
        EFStartFx5      matlab.ui.control.NumericEditField
        EFStartFx4      matlab.ui.control.NumericEditField
        EFStartX6       matlab.ui.control.NumericEditField
        LFx             matlab.ui.control.Label
        LX              matlab.ui.control.Label
        EFStartFx10     matlab.ui.control.NumericEditField
        EFStartX10      matlab.ui.control.NumericEditField
        EFStartFx9      matlab.ui.control.NumericEditField
        EFStartX9       matlab.ui.control.NumericEditField
        EFStartFx8      matlab.ui.control.NumericEditField
        EFStartX8       matlab.ui.control.NumericEditField
        EFStartFx7      matlab.ui.control.NumericEditField
        EFStartX7       matlab.ui.control.NumericEditField
        EFStartFx6      matlab.ui.control.NumericEditField
        EFStartX5       matlab.ui.control.NumericEditField
        EFStartX4       matlab.ui.control.NumericEditField
        EFStartFx3      matlab.ui.control.NumericEditField
        EFStartX3       matlab.ui.control.NumericEditField
        EFStartX2       matlab.ui.control.NumericEditField
        EFStartFx1      matlab.ui.control.NumericEditField
        EFStartX1       matlab.ui.control.NumericEditField
        GLSearch        matlab.ui.container.GridLayout
        L3              matlab.ui.control.Label
        EFSearch1       matlab.ui.control.NumericEditField
        GLMathSign      matlab.ui.container.GridLayout
        BMathSign       matlab.ui.control.Button
        L2              matlab.ui.control.Label
        L1              matlab.ui.control.Label
        GLMenue         matlab.ui.container.GridLayout
        InfoButton      matlab.ui.control.Button
        RollBackButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollBackButton
        function RollBackButtonPushed(app, event)
            % Close window SecondWindow
            delete(app.UIRoot)

            % Open RootWindow
            RootWindow
        end

        % Button pushed function: BMathSign
        function BMathSignButtonPushed(app, event)
            % Собираем значения, которые ввёл пользователь.
            user_x_val_1 = get(app.EFSearch1, 'Value');
            % user_x_val_2 = get(app.EFSearch2, 'Value');

            app_x_arr = [app.EFStartX1, app.EFStartX2, app.EFStartX3, ...
                         app.EFStartX4, app.EFStartX5, app.EFStartX6, ...
                         app.EFStartX7, app.EFStartX8, app.EFStartX9, ...
                         app.EFStartX10];

            app_fx_arr = [app.EFStartFx1, app.EFStartFx2, app.EFStartFx3, ...
                          app.EFStartFx4, app.EFStartFx5, app.EFStartFx6, ...
                          app.EFStartFx7, app.EFStartFx8, app.EFStartFx9, ...
                          app.EFStartFx10];
            
            x_arr = [ ];
            fx_arr = [ ];

            for index = 1 : 1 : 10
                x_arr(end + 1) = get(app_x_arr(index), 'Value');
                fx_arr(end + 1) = get(app_fx_arr(index), 'Value');
            end

            index_arr = 1 : 1 : 10;

            % Производим балансировку индексов ( короче делаем так
            % чтобы x(i) <= x(i+1) ).
            for index_1 = 1 : 1 : 9
                for index_2 = index_1 + 1  : 1 : 10
                    if x_arr(index_2) < x_arr(index_1)
                        tmp = index_arr(index_1);
                        index_arr(index_1) = index_arr(index_2);
                        index_arr(index_2) = tmp;
                    end
                end
            end

            % Выполняем проверку корректности введённых значений (
            % Типо если x(i) == x(k), но Fx(i) != Fx(k), то выдаем
            % ошибку ).


            % Производим расчёт значений Fx для введённых пользователем
            % значений x.
            [last_div_diff_val, div_diff_arr] = get_divided_difference(fx_arr, x_arr, index_arr);

            div_diff_arr = [fx_arr(1), div_diff_arr, last_div_diff_val];

            user_fx_val_1 = get_new_fx_value(div_diff_arr, x_arr, user_x_val_1);
            % user_fx_val_2 = get_new_fx_value(div_diff_arr, x_arr, user_x_val_2);

            % Выводим расчитанные значения Fx на экран.
            set(app.L3, 'Text', num2str(round(user_fx_val_1, 3)))
            % set(app.L4, 'Text', num2str(round(user_fx_val_2, 3)))

            % Выводим сообщение с ответом, расчитанным по встроенным
            % функциям MATLAB.
            new_val = spline(x_arr, fx_arr, user_x_val_1);

            str = strcat('F(x) value =', num2str(round(new_val, 4)));
            message = msgbox(str, 'Correct answer');
            %set(message, 'Clipping', 'off');
            set(message, 'Position', [500 400 200 50]);
            
            message;

            % Рисуем графики
            plot(app.UIAGraphic, x_arr, fx_arr)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRoot = uifigure('Visible', 'off');
            app.UIRoot.Name = 'Интерполирование функции по формулам "Ньютона"';
            app.GLRoot = uigridlayout(app.UIRoot);
            app.GLMenue = uigridlayout(app.GLRoot);
            app.RollBackButton = uibutton(app.GLMenue, 'push');
            app.RollBackButton.ButtonPushedFcn = createCallbackFcn(app, @RollBackButtonPushed, true);
            app.RollBackButton.Text = 'RollBack';
            app.InfoButton = uibutton(app.GLMenue, 'push');
            app.InfoButton.Text = 'Info';
            app.GLBody = uigridlayout(app.GLRoot);
            app.GLInputOutput = uigridlayout(app.GLBody);
            app.L1 = uilabel(app.GLInputOutput);
            app.L1.Text = 'Известные значения:';
            app.L2 = uilabel(app.GLInputOutput);
            app.L2.Text = 'Найти:';
            app.GLMathSign = uigridlayout(app.GLInputOutput);
            app.BMathSign = uibutton(app.GLMathSign, 'push');
            app.BMathSign.ButtonPushedFcn = createCallbackFcn(app, @BMathSignButtonPushed, true);
            app.BMathSign.Text = '=';
            app.GLSearch = uigridlayout(app.GLInputOutput);
            app.EFSearch1 = uieditfield(app.GLSearch, 'numeric');
            app.EFSearch1.Limits = [-999 999];
            app.EFSearch1.Value = 1.25;
            app.L3 = uilabel(app.GLSearch);
            app.L3.Text = 'ans';
            app.GLStartValue = uigridlayout(app.GLInputOutput);
            app.EFStartX1 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX1.Limits = [-999 999];
            app.EFStartX1.Value = 1.2;
            app.EFStartFx1 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx1.Limits = [-999 999];
            app.EFStartFx1.Value = 9.276;
            app.EFStartX2 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX2.Limits = [-999 999];
            app.EFStartX2.Value = 1.5;
            app.EFStartX3 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX3.Limits = [-999 999];
            app.EFStartX3.Value = 1.7;
            app.EFStartFx3 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx3.Limits = [-999 999];
            app.EFStartFx3.Value = 9.816;
            app.EFStartX4 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX4.Limits = [-999 999];
            app.EFStartX4.Value = 2;
            app.EFStartX5 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX5.Limits = [-999 999];
            app.EFStartX5.Value = 2.4;
            app.EFStartFx6 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx6.Limits = [-999 999];
            app.EFStartFx6.Value = 10.622;
            app.EFStartX7 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX7.Limits = [-999 999];
            app.EFStartX7.Value = 3.1;
            app.EFStartFx7 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx7.Value = 10.897;
            app.EFStartX8 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX8.Limits = [-999 999];
            app.EFStartX8.Value = 3.3;
            app.EFStartFx8 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx8.Limits = [-999 999];
            app.EFStartFx8.Value = 11.16;
            app.EFStartX9 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX9.Limits = [-999 999];
            app.EFStartX9.Value = 3.5;
            app.EFStartFx9 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx9.Limits = [-999 999];
            app.EFStartFx9.Value = 11.434;
            app.EFStartX10 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX10.Limits = [-999 999];
            app.EFStartX10.Value = 3.9;
            app.EFStartFx10 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx10.Limits = [-999 999];
            app.EFStartFx10.Value = 11.703;
            app.LX = uilabel(app.GLStartValue);
            app.LX.Text = 'X';
            app.LFx = uilabel(app.GLStartValue);
            app.LFx.Text = 'F(x)';
            app.EFStartX6 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartX6.Limits = [-999 999];
            app.EFStartX6.Value = 2.6;
            app.EFStartFx4 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx4.Limits = [-999 999];
            app.EFStartFx4.Value = 10.088;
            app.EFStartFx5 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx5.Limits = [-999 999];
            app.EFStartFx5.Value = 10.355;
            app.EFStartFx2 = uieditfield(app.GLStartValue, 'numeric');
            app.EFStartFx2.Limits = [-999 999];
            app.EFStartFx2.Value = 9.549;
            app.GLGraphic = uigridlayout(app.GLBody);
            app.UIAGraphic = uiaxes(app.GLGraphic);
            xlabel(app.UIAGraphic, 'X')
            ylabel(app.UIAGraphic, 'Y')
            zlabel(app.UIAGraphic, 'Z')
            app.LGraphic = uilabel(app.GLGraphic);
            app.LGraphic.Text = 'График заданной функцуии:';
            % Show the figure after all components are created
            app.UIRoot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecondWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRoot)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRoot)
        end
    end
end