classdef ThreeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRoot              matlab.ui.Figure
        GLRoot              matlab.ui.container.GridLayout
        GLBody              matlab.ui.container.GridLayout
        GLGraphics          matlab.ui.container.GridLayout
        L2                  matlab.ui.control.Label
        L1                  matlab.ui.control.Label
        UIAFunc             matlab.ui.control.UIAxes
        UIAFuncApproximate  matlab.ui.control.UIAxes
        GLInputOutput       matlab.ui.container.GridLayout
        GLResult            matlab.ui.container.GridLayout
        LResult             matlab.ui.control.Label
        GLMatchFunc         matlab.ui.container.GridLayout
        GLEditValue         matlab.ui.container.GridLayout
        EFVAlueC            matlab.ui.control.NumericEditField
        LValueC             matlab.ui.control.Label
        IFunc               matlab.ui.control.Image
        GLMathSign          matlab.ui.container.GridLayout
        BCalculate          matlab.ui.control.Button
        GLMenue             matlab.ui.container.GridLayout
        InfoButton          matlab.ui.control.Button
        RollBackButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollBackButton
        function RollBackButtonPushed(app, event)
            % Close ThreeWindow
            delete(app.UIRoot)

            % Open RootWindow
            RootWindow
        end

        % Button pushed function: BCalculate
        function BCalculateButtonPushed(app, event)
            % Забираем значение верхней границы интегрирования.
            upper_limit = get(app.EFVAlueC, 'Value');

            % Производим расчёт интеграла
            lower_limit = 0;
            step = 0.001;

            if upper_limit - lower_limit <= step
                set(app.LResult, 'Text', '0')
                return
            end
            
            % Расчитанные значения аргумента и функции
            x_input = lower_limit : step : upper_limit;
            y_input = exp(-(x_input).^2 ./ 2);

            % Считаем интегральную функци и собственно численое
            % значение интеграла
            y_output = zeros(1, length(y_input));
            integral_value = 0;

            for index = 2:1:length(y_output) - 1
                temp_step = ( x_input(index) + x_input(index + 1) ) / 2;
                temp_pice = ( y_input(index) + y_input(index + 1) + ...
                            4 * exp(-(temp_step)^2 / 2) ) * step / 6;
                
                y_output(index) = integral_value + temp_pice;
                integral_value = integral_value + temp_pice;
            end
            
            % Записываем последний элемент
            y_output(length(y_output)) = integral_value;
            
            % Выводим результат интеграла на экран
            set(app.LResult, 'Text', num2str(round(integral_value, 3)))

            %disp(integral_value)

            % Выводим сообщение с ответом, расчитанным по встроенным
            % функциям MATLAB.
            myfunc = @(x) exp(-(x).^2 ./ 2);

            integral_value_matlab = quad(myfunc, 0, upper_limit);

            str = strcat('Matlab answer =', num2str(round(integral_value_matlab, 4)));
            message = msgbox(str, 'Correct answer');
            %set(message, 'Clipping', 'off');
            set(message, 'Position', [500 400 200 50]);
            
            message;

            % Рисуем графики
            plot(app.UIAFunc, x_input, y_input)
            plot(app.UIAFuncApproximate, x_input, y_output)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRoot = uifigure('Visible', 'off');
            app.UIRoot.Name = 'Расчёт интеграла методом "Симпсона"';
            app.GLRoot = uigridlayout(app.UIRoot);
            app.GLMenue = uigridlayout(app.GLRoot);
            app.RollBackButton = uibutton(app.GLMenue, 'push');
            app.RollBackButton.ButtonPushedFcn = createCallbackFcn(app, @RollBackButtonPushed, true);
            app.RollBackButton.Text = 'RollBack';
            app.InfoButton = uibutton(app.GLMenue, 'push');
            app.InfoButton.Text = 'Info';
            app.GLBody = uigridlayout(app.GLRoot);
            app.GLInputOutput = uigridlayout(app.GLBody);
            app.GLMathSign = uigridlayout(app.GLInputOutput);
            app.BCalculate = uibutton(app.GLMathSign, 'push');
            app.BCalculate.ButtonPushedFcn = createCallbackFcn(app, @BCalculateButtonPushed, true);
            app.BCalculate.Text = '=';
            app.GLMatchFunc = uigridlayout(app.GLInputOutput);
            app.IFunc = uiimage(app.GLMatchFunc);
            app.IFunc.ImageSource = 'math_func_3.png';
            app.LValueC = uilabel(app.GLMatchFunc);
            app.LValueC.Text = ', где  C = ';
            app.GLEditValue = uigridlayout(app.GLMatchFunc);
            app.EFVAlueC = uieditfield(app.GLEditValue, 'numeric');
            app.EFVAlueC.Limits = [0 99];
            app.EFVAlueC.Value = 0.166;
            app.GLResult = uigridlayout(app.GLInputOutput);
            app.LResult = uilabel(app.GLResult);
            app.LResult.Text = 'ans';
            app.GLGraphics = uigridlayout(app.GLBody);
            app.UIAFuncApproximate = uiaxes(app.GLGraphics);
            xlabel(app.UIAFuncApproximate, 'X')
            ylabel(app.UIAFuncApproximate, 'Y')
            zlabel(app.UIAFuncApproximate, 'Z')
            app.UIAFunc = uiaxes(app.GLGraphics);
            xlabel(app.UIAFunc, 'X')
            ylabel(app.UIAFunc, 'Y')
            zlabel(app.UIAFunc, 'Z')
            app.L1 = uilabel(app.GLGraphics);
            app.L1.Text = 'График исходной функции:';
            app.L2 = uilabel(app.GLGraphics);
            app.L2.Text = 'График интегральной функции:';
            % Show the figure after all components are created
            app.UIRoot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRoot)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRoot)
        end
    end
end